classdef Styler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StyleTransferUIFigure     matlab.ui.Figure
        OutputText                matlab.ui.control.TextArea
        InputImageAxes            matlab.ui.control.UIAxes
        StyleImageAxes            matlab.ui.control.UIAxes
        OutputImageAxes           matlab.ui.control.UIAxes
        RunSwitch                 matlab.ui.control.ToggleSwitch
        LocateInputButton         matlab.ui.control.Button
        LocateStyleButton         matlab.ui.control.Button
        NameEditFieldLabel        matlab.ui.control.Label
        NameEditField             matlab.ui.control.EditField
        SaveButton                matlab.ui.control.Button
        Slider                    matlab.ui.control.Slider
        Spinner                   matlab.ui.control.Spinner
        InputSwitch               matlab.ui.control.Switch
        StyleSwitch               matlab.ui.control.Switch
        OutputSwitch              matlab.ui.control.Switch
        DropDown                  matlab.ui.control.DropDown
        InputResultStyleLabel     matlab.ui.control.Label
        PrepareButton             matlab.ui.control.Button
        PlotButton                matlab.ui.control.Button
        PlotOriginalButton        matlab.ui.control.Button
        LearningRateSpinnerLabel  matlab.ui.control.Label
        LearningRateSpinner       matlab.ui.control.Spinner
        NoiseDropDownLabel        matlab.ui.control.Label
        NoiseDropDown             matlab.ui.control.DropDown
        NoiseSlider               matlab.ui.control.Slider
        NoiseKnob                 matlab.ui.control.Knob
        ExtDropDown               matlab.ui.control.DropDown
        W1Label                   matlab.ui.control.Label
        W1Slider                  matlab.ui.control.Slider
        Label                     matlab.ui.control.Label
        W5Slider                  matlab.ui.control.Slider
        W2Slider                  matlab.ui.control.Slider
        W4Slider                  matlab.ui.control.Slider
        W3Slider                  matlab.ui.control.Slider
    end

    
    properties (Access = private)
        InputImagePath (1,1) string
        InputImageOriginal
        StyleImagePath (1,1) string
        StyleImageOriginal
        
        RunnerObj (1,1)
        NetObj    (1,1)
        Images    (1,1) %: content, style, transfer
    end

    properties ( Access = public )
        OutputImageSize (1,2) uint16
        OutputImage
    end
    
    methods (Access = public)
        
        function Log(app,t)
            arguments
                app
                t (1,1) string
            end
            app.OutputText.Value = [t; app.OutputText.Value];
            drawnow
        end
        
        function B = IsRunSwitchOn(app)
            B = string(app.RunSwitch.Value)=="On";
        end
        
        function ShowOutput(app,Title)
            arguments
                app
                Title (1,1) string
            end
            if string(app.OutputSwitch.Value)~="Off"
                imagesc(app.OutputImageAxes,app.OutputImage);
                title(app.OutputImageAxes,Title)
                axis(app.OutputImageAxes,"equal")
            end
        end
        
        function UpdateIteration(app,Iteration)
            arguments
                app
                Iteration (1,1) double {mustBePositive(Iteration)}
            end
            app.Slider.Value = Iteration;
            if app.Slider.Value == app.Slider.Limits(2)
                app.Slider.Limits(2)=app.Slider.Limits(2)*2;
                drawnow
            else
                pause(0.01)
            end
            if Iteration==app.Spinner.Value
                app.RunSwitch.Value = "Off";
                app.Log("Preset itaration limit " + app.Spinner.Value + " reached.")
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LocateInputButton
        function LocateInputButtonPushed(app, event)
            [File,Folder] = uigetfile("*.jpg","Select input image","in");
            if isnumeric(File)
                return
            end
            app.InputImagePath = fullfile(Folder,File);
            app.InputImageOriginal = imread(app.InputImagePath);
            imagesc(app.InputImageAxes,app.InputImageOriginal);
            axis(app.InputImageAxes,"equal")
            app.InputSwitch.Value = 'On';
            app.Log("Selected " + app.InputImagePath + " for input");
            app.OutputImageSize = size(app.InputImageOriginal,[1 2]);
        end

        % Button pushed function: LocateStyleButton
        function LocateStyleButtonPushed(app, event)
            [File,Folder] = uigetfile("*.jpg","Select style image","in");
            if isnumeric(File)
                return
            end
            app.StyleImagePath = fullfile(Folder,File);
            app.StyleImageOriginal = imread(app.StyleImagePath);
            imagesc(app.StyleImageAxes,app.StyleImageOriginal);
            axis(app.StyleImageAxes,"equal")
            app.StyleSwitch.Value = 'On';
            app.Log("Selected " + app.StyleImagePath + " for style");  
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            Name = string(app.NameEditField.Value);
            Ext = string(app.ExtDropDown.Value);
            if Name==""
                return
            elseif exist(fullfile(pwd,Name+Ext),'file')
                app.Log(Name + " already exists.")
            elseif ~isempty(app.OutputImage)
                imwrite(app.OutputImage,Name+Ext);
                app.Log(Name+Ext + " written to current folder.")
            else
                app.Log("Nothing to save.")
            end
        end

        % Value changed function: RunSwitch
        function RunSwitchValueChanged(app, event)
            switch app.RunSwitch.Value
                case "On"
                    app.RunnerObj.IterateFrom(app.NetObj,app.Slider.Value)
                case "Off"
            end
            
        end

        % Button pushed function: PrepareButton
        function PrepareButtonPushed(app, event)
            app.Log("Preparing ...")
            app.NetObj = Nets.StyleNet.Factory(app.DropDown.Value,app);
            app.NetObj.SetLearningRate( app.LearningRateSpinner.Value);
            app.NetObj.SetNoise( app.NoiseKnob.Value ...
                               , app.NoiseSlider.Value / 10 ...
                               , categorical(string(app.NoiseDropDown.Value )));  
            app.NetObj.SetWeights([app.W1Slider.Value app.W2Slider.Value app.W3Slider.Value app.W4Slider.Value app.W5Slider.Value]);
            app.Images = app.NetObj.PreprocessImages(app.StyleImageOriginal,app.InputImageOriginal);
            app.Images.transfer = app.NetObj.InitializeTransferImage(app.Images.content);
            app.RunnerObj = Nets.Runner(app);
            app.RunnerObj.TrainNetwork(app.Images,app.NetObj);
            app.Slider.Value=1;
            app.Log("... prepared.")
        end

        % Value changed function: InputSwitch
        function InputSwitchValueChanged(app, event)
            if string(app.InputSwitch.Value)=="Off"
                imagesc(app.InputImageAxes,0.5*ones(3,3,3));
            else
                imagesc(app.InputImageAxes,app.InputImageOriginal);
            end            
        end

        % Value changed function: StyleSwitch
        function StyleSwitchValueChanged(app, event)
            if string(app.StyleSwitch.Value)=="Off"
                imagesc(app.StyleImageAxes,0.5*ones(3,3,3));
            else
                imagesc(app.StyleImageAxes,app.StyleImageOriginal);
            end            
        end

        % Value changed function: OutputSwitch
        function OutputSwitchValueChanged(app, event)
            if string(app.OutputSwitch.Value)=="Off"
                imagesc(app.OutputImageAxes,0.5*ones(3,3,3));
                title(app.OutputImageAxes,"hidden");
            else
                imagesc(app.OutputImageAxes,app.OutputImage);
            end              
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if ~isempty(app.NetObj)
                app.NetObj.PlotLayerGraph("After");
            end
        end

        % Button pushed function: PlotOriginalButton
        function PlotOriginalButtonPushed(app, event)
            if ~isempty(app.NetObj)
                app.NetObj.PlotLayerGraph("Before");
            end
        end

        % Value changed function: LearningRateSpinner
        function LearningRateSpinnerValueChanged(app, event)
            if ~isempty(app.NetObj)
                app.NetObj.SetLearningRate( app.LearningRateSpinner.Value );
            end            
        end

        % Value changed function: NoiseDropDown, NoiseKnob, 
        % NoiseSlider
        function NoiseKnobValueChanged(app, event)
            app.Log("Press 'Prepare' for a change in noise parameters to take effect.")
        end

        % Value changed function: W1Slider
        function W1SliderValueChanged(app, event)
            app.NetObj.WeightChanged(1,app.W1Slider.Value);
        end

        % Value changed function: W2Slider
        function W2SliderValueChanged(app, event)
            app.NetObj.WeightChanged(2,app.W2Slider.Value);
        end

        % Value changed function: W3Slider
        function W3SliderValueChanged(app, event)
            app.NetObj.WeightChanged(3,app.W3Slider.Value);
        end

        % Value changed function: W4Slider
        function W4SliderValueChanged(app, event)
            app.NetObj.WeightChanged(4,app.W4Slider.Value);
        end

        % Value changed function: W5Slider
        function W5SliderValueChanged(app, event)
            app.NetObj.WeightChanged(5,app.W5Slider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StyleTransferUIFigure = uifigure('Visible', 'off');
            app.StyleTransferUIFigure.Name = 'Style Transfer ';
            app.OutputText = uitextarea(app.StyleTransferUIFigure);
            app.InputImageAxes = uiaxes(app.StyleTransferUIFigure);
            title(app.InputImageAxes, '')
            xlabel(app.InputImageAxes, '')
            ylabel(app.InputImageAxes, '')
            app.InputImageAxes.XTick = [];
            app.InputImageAxes.YTick = [];
            app.InputImageAxes.Visible = 'off';
            app.StyleImageAxes = uiaxes(app.StyleTransferUIFigure);
            title(app.StyleImageAxes, '')
            xlabel(app.StyleImageAxes, '')
            ylabel(app.StyleImageAxes, '')
            app.StyleImageAxes.XTick = [];
            app.StyleImageAxes.YTick = [];
            app.StyleImageAxes.Visible = 'off';
            app.OutputImageAxes = uiaxes(app.StyleTransferUIFigure);
            title(app.OutputImageAxes, '')
            xlabel(app.OutputImageAxes, '')
            ylabel(app.OutputImageAxes, '')
            app.OutputImageAxes.XTick = [];
            app.OutputImageAxes.YTick = [];
            app.RunSwitch = uiswitch(app.StyleTransferUIFigure, 'toggle');
            app.RunSwitch.ValueChangedFcn = createCallbackFcn(app, @RunSwitchValueChanged, true);
            app.LocateInputButton = uibutton(app.StyleTransferUIFigure, 'push');
            app.LocateInputButton.ButtonPushedFcn = createCallbackFcn(app, @LocateInputButtonPushed, true);
            app.LocateInputButton.Text = 'Locate';
            app.LocateStyleButton = uibutton(app.StyleTransferUIFigure, 'push');
            app.LocateStyleButton.ButtonPushedFcn = createCallbackFcn(app, @LocateStyleButtonPushed, true);
            app.LocateStyleButton.Text = 'Locate';
            app.NameEditFieldLabel = uilabel(app.StyleTransferUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.StyleTransferUIFigure, 'text');
            app.SaveButton = uibutton(app.StyleTransferUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Slider = uislider(app.StyleTransferUIFigure);
            app.Slider.Limits = [1 1000];
            app.Slider.Value = 1;
            app.Spinner = uispinner(app.StyleTransferUIFigure);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.Value = Inf;
            app.InputSwitch = uiswitch(app.StyleTransferUIFigure, 'slider');
            app.InputSwitch.ValueChangedFcn = createCallbackFcn(app, @InputSwitchValueChanged, true);
            app.InputSwitch.Value = 'On';
            app.StyleSwitch = uiswitch(app.StyleTransferUIFigure, 'slider');
            app.StyleSwitch.ValueChangedFcn = createCallbackFcn(app, @StyleSwitchValueChanged, true);
            app.StyleSwitch.Value = 'On';
            app.OutputSwitch = uiswitch(app.StyleTransferUIFigure, 'slider');
            app.OutputSwitch.ValueChangedFcn = createCallbackFcn(app, @OutputSwitchValueChanged, true);
            app.OutputSwitch.Value = 'On';
            app.DropDown = uidropdown(app.StyleTransferUIFigure);
            app.DropDown.Items = {'vgg19', 'vgg19 + norm 1', 'vgg19 + norm 2', 'vgg19 + norm 3'};
            app.DropDown.Value = 'vgg19';
            app.InputResultStyleLabel = uilabel(app.StyleTransferUIFigure);
            app.InputResultStyleLabel.Text = '< Input | Result | Style >';
            app.PrepareButton = uibutton(app.StyleTransferUIFigure, 'push');
            app.PrepareButton.ButtonPushedFcn = createCallbackFcn(app, @PrepareButtonPushed, true);
            app.PrepareButton.Text = 'Prepare';
            app.PlotButton = uibutton(app.StyleTransferUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotOriginalButton = uibutton(app.StyleTransferUIFigure, 'push');
            app.PlotOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOriginalButtonPushed, true);
            app.PlotOriginalButton.Text = 'Plot';
            app.LearningRateSpinnerLabel = uilabel(app.StyleTransferUIFigure);
            app.LearningRateSpinnerLabel.Text = 'Learning Rate';
            app.LearningRateSpinner = uispinner(app.StyleTransferUIFigure);
            app.LearningRateSpinner.Step = 0.1;
            app.LearningRateSpinner.Limits = [0 99];
            app.LearningRateSpinner.ValueChangedFcn = createCallbackFcn(app, @LearningRateSpinnerValueChanged, true);
            app.LearningRateSpinner.Value = 2;
            app.NoiseDropDownLabel = uilabel(app.StyleTransferUIFigure);
            app.NoiseDropDownLabel.Text = 'Noise';
            app.NoiseDropDown = uidropdown(app.StyleTransferUIFigure);
            app.NoiseDropDown.Items = {'White', 'Normal'};
            app.NoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseKnobValueChanged, true);
            app.NoiseDropDown.Value = 'White';
            app.NoiseSlider = uislider(app.StyleTransferUIFigure);
            app.NoiseSlider.Limits = [0 10];
            app.NoiseSlider.MajorTicks = [0 2 4 6 8 10];
            app.NoiseSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseKnobValueChanged, true);
            app.NoiseSlider.MinorTicks = [];
            app.NoiseSlider.Value = 7;
            app.NoiseKnob = uiknob(app.StyleTransferUIFigure, 'continuous');
            app.NoiseKnob.ValueChangedFcn = createCallbackFcn(app, @NoiseKnobValueChanged, true);
            app.NoiseKnob.Value = 20;
            app.ExtDropDown = uidropdown(app.StyleTransferUIFigure);
            app.ExtDropDown.Items = {'.jpg', '.png', '.tif'};
            app.ExtDropDown.Value = '.jpg';
            app.W1Label = uilabel(app.StyleTransferUIFigure);
            app.W1Label.Text = 'W 1';
            app.W1Slider = uislider(app.StyleTransferUIFigure);
            app.W1Slider.Limits = [0 5];
            app.W1Slider.MajorTicks = [1 2 3 4];
            app.W1Slider.ValueChangedFcn = createCallbackFcn(app, @W1SliderValueChanged, true);
            app.W1Slider.Value = 1;
            app.Label = uilabel(app.StyleTransferUIFigure);
            app.Label.Text = '5';
            app.W5Slider = uislider(app.StyleTransferUIFigure);
            app.W5Slider.Limits = [0 5];
            app.W5Slider.MajorTicks = [1 2 3 4];
            app.W5Slider.ValueChangedFcn = createCallbackFcn(app, @W5SliderValueChanged, true);
            app.W5Slider.Value = 1;
            app.W2Slider = uislider(app.StyleTransferUIFigure);
            app.W2Slider.Limits = [0 5];
            app.W2Slider.MajorTicks = [1 2 3 4];
            app.W2Slider.ValueChangedFcn = createCallbackFcn(app, @W2SliderValueChanged, true);
            app.W2Slider.Value = 1;
            app.W4Slider = uislider(app.StyleTransferUIFigure);
            app.W4Slider.Limits = [0 5];
            app.W4Slider.MajorTicks = [];
            app.W4Slider.ValueChangedFcn = createCallbackFcn(app, @W4SliderValueChanged, true);
            app.W4Slider.Value = 1;
            app.W3Slider = uislider(app.StyleTransferUIFigure);
            app.W3Slider.Limits = [0 5];
            app.W3Slider.MajorTicks = [1 2 3 4];
            app.W3Slider.ValueChangedFcn = createCallbackFcn(app, @W3SliderValueChanged, true);
            app.W3Slider.Value = 1;
            % Show the figure after all components are created
            app.StyleTransferUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Styler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StyleTransferUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StyleTransferUIFigure)
        end
    end
end